classdef BinaryTreeVisualizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        BaxkButton                 matlab.ui.control.Button
        ImportButton_2             matlab.ui.control.Button
        Help                       matlab.ui.control.Button
        NodeCountLabel             matlab.ui.control.Label
        TraversalButton            matlab.ui.control.Button
        DeleteButton               matlab.ui.control.Button
        EnterNumberEditField       matlab.ui.control.NumericEditField
        EnterNumberEditFieldLabel  matlab.ui.control.Label
        SearchButton               matlab.ui.control.Button
        ClearButton                matlab.ui.control.Button
        InsertButton               matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        BST % Description
        TraversalList
        MainHubAppWindow
        % Add these to your public properties block if not already there:
        ImportSequenceEditField      matlab.ui.control.EditField
        ImportSequenceEditFieldLabel matlab.ui.control.Label
        ImportButton                 matlab.ui.control.Button

    end
    
    methods (Access = private)
        
       function smartInsert(app, currID, val, x, y)
            nVal = app.BST.get(currID);
            children = app.BST.getchildren(currID);
            
            if val < nVal
                % MUST GO LEFT
                leftNode = [];
                for c = children
                    if app.BST.get(c) < nVal
                        leftNode = c; break;
                    end
                end
                
                if isempty(leftNode)
                    app.BST = app.BST.addnode(currID, val);
                else
                    app.smartInsert(leftNode, val, x-1, y-1);
                end
            else
                % MUST GO RIGHT
                rightNode = [];
                for c = children
                    if app.BST.get(c) > nVal
                        rightNode = c; break;
                    end
                end
                
                if isempty(rightNode)
                    app.BST = app.BST.addnode(currID, val);
                else
                    app.smartInsert(rightNode, val, x+1, y-1);
                end
            end
       end

       function h = updatePlot(app) 
        cla(app.UIAxes); 

        if isempty(app.BST), return; end 

        % 1. Build the graph object 

        p = app.BST.Parent; 

        if numel(p) == 1
            G = digraph([], []); G = addnode(G, 1); 
        else 
            G = digraph(p(2:end), 2:numel(p)); 
        end 
        % 2. Calculate "Source" nodes for better layout 
        % We tell MATLAB the root is the start of the layers 

        h = plot(app.UIAxes, G, 'Layout', 'layered', 'Direction', 'down', ... 
        'MarkerSize', 12, 'NodeColor', [0 0.45 0.74], 'LineWidth', 1.5, ... 
        'Sources', 1); 

        % 3. FORCE LEFT-RIGHT ORDER 
        % We get the values and use them to sort the X-coordinates 
        % so that 25 is ALWAYS to the left of 75, regardless of deletion order. 

        vals = [app.BST.Node{:}]; 
        [~, sortIdx] = sort(vals); 

        % This adjusts the X-positions slightly to keep the BST rule visual 

        h.XData(sortIdx) = linspace(min(vals), max(vals), numel(vals)); 

        % 4. Add labels 

        h.NodeLabel = string(vals); 
        axis(app.UIAxes, 'tight'); 
        drawnow; 
        end 


       function inOrder(app, curr)
            children = app.BST.getchildren(curr);
            
            % --- FIX: SORT CHILDREN BY VALUE ---
            if numel(children) > 1
                childVals = [app.BST.Node{children}];
                [~, sortIdx] = sort(childVals);
                children = children(sortIdx);
            end
        
            % 1. Left
            if numel(children) >= 1, app.inOrder(children(1)); end
            
            % 2. Root (Name it in the middle)
            app.TraversalList(end+1) = curr;
            
            % 3. Right
            if numel(children) >= 2, app.inOrder(children(2)); end
        end

        
        function preOrder(app, curr)
            children = app.BST.getchildren(curr);
            
            % --- FIX: SORT CHILDREN BY VALUE ---
            if numel(children) > 1
                childVals = [app.BST.Node{children}];
                [~, sortIdx] = sort(childVals);
                children = children(sortIdx);
            end
        
            % 1. Root (Name it first)
            app.TraversalList(end+1) = curr;
            
            % 2. Left
            if numel(children) >= 1, app.preOrder(children(1)); end
            
            % 3. Right
            if numel(children) >= 2, app.preOrder(children(2)); end
        end

        
        function postOrder(app, curr)
            children = app.BST.getchildren(curr);
            
            % --- FIX: SORT CHILDREN BY VALUE ---
            if numel(children) > 1
                childVals = [app.BST.Node{children}];
                [~, sortIdx] = sort(childVals);
                children = children(sortIdx); % Now children(1) is Left, children(2) is Right
            end
        
            % 1. Left
            if numel(children) >= 1, app.postOrder(children(1)); end
            % 2. Right
            if numel(children) >= 2, app.postOrder(children(2)); end
            % 3. Root
            app.TraversalList(end+1) = curr;
        end


        function levelOrder(app)
            % BREADTH FIRST: Visit nodes floor-by-floor, Left-to-Right
            queue = [1]; % Start with root index
            app.TraversalList = []; 
            
            while ~isempty(queue)
                curr = queue(1);
                queue(1) = []; % Pop
                app.TraversalList(end+1) = curr;
                
                % Get children
                children = app.BST.getchildren(curr);
                
                if ~isempty(children)
                    % --- FIX: SORT CHILDREN LEFT-TO-RIGHT ---
                    % Get values of children to ensure the smaller one (Left) is first
                    childVals = [app.BST.Node{children}];
                    [~, sortIdx] = sort(childVals);
                    sortedChildren = children(sortIdx);
                    
                    % Add sorted children to the back of the queue
                    queue = [queue, sortedChildren]; 
                end
            end
        end


        function pathTraversal(app, target)
            % PATH TRAVERSAL: Find the specific road to a value
            app.TraversalList = []; 
            currID = 1; % Start at root
            
            while ~isempty(currID)
                app.TraversalList(end+1) = currID;
                nodeVal = app.BST.get(currID);
                
                if target == nodeVal, break; end 
                
                % Standard BST logic to move down the tree
                children = app.BST.getchildren(currID);
                nextID = [];
                for c = children
                    cVal = app.BST.get(c);
                    if (target < nodeVal && cVal < nodeVal) || ...
                       (target > nodeVal && cVal > nodeVal)
                        nextID = c;
                        break;
                    end
                end
                currID = nextID; 
            end
        end

                function insertBalanced(app, vals)
            % This is the recursive helper that builds the balanced tree
            if isempty(vals), return; end
            
            % 1. Find the mathematical middle (the Median)
            midIdx = ceil(numel(vals) / 2);
            midVal = vals(midIdx);
            
            % 2. Insert that middle value as the parent
            if isempty(app.BST)
                app.BST = tree(midVal);
            else
                % Use your existing logic to place it correctly
                app.smartInsert(1, midVal, 0, 0);
            end
            
            % 3. Recursively do the same for the left and right halves
            app.insertBalanced(vals(1:midIdx-1));   % Left side
            app.insertBalanced(vals(midIdx+1:end)); % Right side
        end
    
        function refreshStats(app)
            if isempty(app.BST)
                count = 0;
            else
                % numel counts how many elements are in the Node cell array
                count = numel(app.BST.Node);
            end
            app.NodeCountLabel.Text = ['Nodes: ' num2str(count)];
        end
        
        function order = calculateBalancedOrder(app, vals, currentOrder)
            % Recursive helper to find insertion order for a balanced tree
            if isempty(vals)
                order = currentOrder;
                return;
            end
            
            % Find the middle node
            midIdx = floor((numel(vals) + 1) / 2);
            midVal = vals(midIdx);
            
            % Append the chosen middle node to our sequential plan
            currentOrder(end+1) = midVal;
            
            % Recursively process the left half (pass 'app' forward)
            currentOrder = app.calculateBalancedOrder(vals(1:midIdx-1), currentOrder);
            
            % Recursively process the right half (pass 'app' forward)
            order = app.calculateBalancedOrder(vals(midIdx+1:end), currentOrder);
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        addpath tinerez;
        app.BST = []; % Ensure tree starts empty

        end

        % Button pushed function: InsertButton
        function InsertButtonPushed(app, event)

            val = app.EnterNumberEditField.Value;
        
            if isempty(app.BST)
                app.BST = tree(val); 
            else
                % --- NEW: CHECK FOR DUPLICATE FIRST ---
                % We check all existing nodes to see if the value is already there
                allNodes = [app.BST.Node{:}];
                if any(allNodes == val)
                    uialert(app.UIFigure, ['The value ' num2str(val) ' already exists!'], ...
                        'Duplicate Found', 'Icon', 'info');
                    return; % Exit early so no animation or insert happens
                end
        
                % --- STEP 1: Fast (but potentially "wrong") Insert ---
                % Simply add it as a child of the root for a split second
                app.BST = app.BST.addnode(1, val); 
                app.updatePlot();
                
                % Pause for 0.5 seconds so you can see the "unordered" state
                pause(0.5);
                
                % --- STEP 2: The "Fix" ---
                % 1. Remove the node we just added
                lastIdx = numel(app.BST.Parent);
                app.BST = app.BST.chop(lastIdx); 
                
                % 2. Re-insert it using the CORRECT BST logic
                app.smartInsert(1, val, 0, 0); 
            end
            
            % Final refresh
            app.updatePlot();
            app.refreshStats(); % Add this


        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.BST = [];      % Deletes the tree data
            app.refreshStats(); % Add this
            cla(app.UIAxes);    % Clears the plot

        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
        
                searchVal = app.EnterNumberEditField.Value;
                if isempty(app.BST), return; end
                
                % 1. Reset the plot to original colors before starting
                h = app.updatePlot(); 
                
                currID = 1;
                found = false;
                lastID = []; % Track the previous node to clean it up
                
                while ~isempty(currID)
                    % --- OPTIONAL: Clean up the previous red node before moving ---
                    if ~isempty(lastID)
                         h.highlight(lastID, 'NodeColor', [0 0.45 0.74], 'MarkerSize', 12); % Back to Blue
                    end
            
                    % Highlight the current node being checked
                    h.highlight(currID, 'NodeColor', 'r', 'MarkerSize', 15);
                    pause(0.4); 
                    
                    nodeVal = app.BST.get(currID);
                    
                    if searchVal == nodeVal
                        found = true;
                        % Success highlight (Green)
                        h.highlight(currID, 'NodeColor', 'g', 'MarkerSize', 20);
                        uialert(app.UIFigure, ['Found ' num2str(searchVal) '!'], 'Success');
                        break;
                    end
                    
                    % Decide which direction to go
                    children = app.BST.getchildren(currID);
                    nextID = [];
                    
                    for c = children
                        cVal = app.BST.get(c);
                        if (searchVal < nodeVal && cVal < nodeVal) || (searchVal > nodeVal && cVal > nodeVal)
                            nextID = c;
                            break;
                        end
                    end
                    
                    lastID = currID; % Store this node so we can turn it blue in the next loop
                    currID = nextID; 
                end
                
                if ~found
                    uialert(app.UIFigure, 'Value not found in the tree.', 'Search Result');
                end
            
                % --- 3. FINAL CLEANUP ---
                % Redraw the whole plot one last time to wipe all highlights away
                app.updatePlot();


        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)

    valToDelete = app.EnterNumberEditField.Value;
    if isempty(app.BST), return; end
    
    % 1. Use "Search Method" to find the node index
    allNodes = [app.BST.Node{:}];
    targetIdx = find(allNodes == valToDelete, 1);
    if isempty(targetIdx), return; end

    children = app.BST.getchildren(targetIdx);

    if numel(children) == 2
        % --- YOUR RULE: RIGHT ONCE ---
        % Look for the right child (the one with a larger value)
        curr = [];
        for c = children
            if app.BST.get(c) > app.BST.get(targetIdx)
                curr = c; break;
            end
        end
        
        % --- YOUR RULE: LEFT ALL THE WAY ---
        % Move left ONLY if a smaller child exists
        while true
            subChildren = app.BST.getchildren(curr);
            if isempty(subChildren), break; end
            
            nextLeft = [];
            for sc = subChildren
                if app.BST.get(sc) < app.BST.get(curr)
                    nextLeft = sc; break;
                end
            end
            
            if isempty(nextLeft), break; end
            curr = nextLeft;
        end
        
        % Replace target value with successor value
        successorVal = app.BST.get(curr);
        app.BST = app.BST.set(targetIdx, successorVal);
        
        % Surgical Delete: Remove only the successor leaf
        app.BST = app.BST.removenode(curr);

        else
        % 0 or 1 child case
        if targetIdx == 1 && ~isempty(children)
            % If deleting the ROOT that has a child/subtree:
            % Use the 'subtree' method (Page 34 of your PDF) to 
            % promote the entire branch starting at the child node.
            app.BST = app.BST.subtree(children(1));
        else
            % If it's a leaf or a middle node, use the surgical delete
            % but wrap it in a try/catch to avoid the Root Error
            try
                app.BST = app.BST.removenode(targetIdx);
            catch
                % If it's the very last node in the tree
                app.BST = [];
            end
        end
    end


    % 2. Clean up and refresh
    if isempty(app.BST.Parent) || isempty(app.BST.Node), app.BST = []; end
    app.updatePlot();
    app.refreshStats(); % Add this

        end

        % Button pushed function: TraversalButton
        function TraversalButtonPushed(app, event)

            if isempty(app.BST), return; end
            
            % 1. Update the Main Menu
            category = uiconfirm(app.UIFigure, 'Choose Traversal Category:', 'Traversal Menu', ...
                'Options', {'Depth First', 'Breadth First', 'Path Traversal', 'Cancel'}, ...
                'DefaultOption', 1, ...
                'CancelOption', 4); % Maps the "X" button to the 'Cancel' option
            
            % Kill the function if they hit X or Cancel
            if isempty(category) || strcmp(category, 'Cancel'), return; end

            % Prepare for animation
            h = app.updatePlot(); 
            app.TraversalList = []; 
            
            % 2. SUB-MENU LOGIC
            switch category
                case 'Depth First'
                   % 2. Update the Depth First Sub-Menu
                    dfType = uiconfirm(app.UIFigure, 'Select Depth First Method:', 'Depth First', ...
                        'Options', {'In-Order', 'Pre-Order', 'Post-Order', 'Cancel'}, ...
                        'DefaultOption', 1, ...
                        'CancelOption', 4); % Maps the "X" button to the 'Cancel' option
                    
                    % Kill the function if they hit X or Cancel
                    if isempty(dfType) || strcmp(dfType, 'Cancel'), return; end

                    if strcmp(dfType, 'In-Order'),     app.inOrder(1);
                    elseif strcmp(dfType, 'Pre-Order'),  app.preOrder(1);
                    else,                              app.postOrder(1); 
                    end
                    
                case 'Breadth First'
                    % Breadth First usually only has one type (Level-Order)
                    % We can run it directly or ask to confirm
                    app.levelOrder();
                    
                case 'Path Traversal'
                    % For Path, we use the value currently in your Edit Field
                    target = app.EnterNumberEditField.Value;
                    app.pathTraversal(target);
            end
            
            % 3. ANIMATION: This runs regardless of which method was chosen above
            if isempty(app.TraversalList)
                uialert(app.UIFigure, 'No path or nodes found.', 'Traversal Error');
                return;
            end
        
            for i = 1:numel(app.TraversalList)
                nodeIdx = app.TraversalList(i);
                h.highlight(nodeIdx, 'NodeColor', 'y', 'MarkerSize', 18);
                pause(0.5); 
            end
            
            % Final summary alert
            pathVals = [app.BST.Node{app.TraversalList}];
            uialert(app.UIFigure, ['Order: ' strjoin(string(pathVals), ' -> ')], 'Traversal Complete');
            app.updatePlot(); 

        end

        % Button pushed function: Help
        function HelpButtonPushed(app, event)
            helpMessage = sprintf([...
                '===== FILE IMPORT FEATURES =====\n' ...
                '● IMPORT FILE BUTTON\n' ...
                'Loads a sequence of numbers from a text (.txt) or comma-separated (.csv) file.\n' ...
                '   • Format: Space or comma-separated (e.g., 50, 25, 75).\n' ...
                '   • Animation: Automatically resets your workspace and builds the new tree node-by-node.\n' ...
                '   • Rules: Silently filters out duplicate numbers to avoid insertion crashes.\n\n' ...
                '● DELETE BUTTON\n' ...s
                '===== INTERACTIVE ACTIONS =====\n' ...
                '● INSERT BUTTON\n' ...
                'Adds your number into the tree. Smaller values go left, larger go right. ' ...
                'Animates the adjustment process.\n\n' ...
                'Surgically removes your number. Uses the in-order successor rule if the target ' ...
                'has two child branches.\n\n' ...
                '● SEARCH BUTTON\n' ...
                'Traces a path down the tree to look for your value. Highlights steps in red ' ...
                'and successes in green.\n\n' ...
                '● TRAVERSAL BUTTON\n' ...
                'Launches sub-menus to animate algorithms (In-Order, Pre-Order, Post-Order, ' ...
                'Level-Order, or Path Traversal) in yellow.\n\n' ...
                '● CLEAR BUTTON\n' ...
                'Deletes the entire active tree from memory and completely resets the graphic axis canvas.\n\n' ...
                '===== INPUT & METRICS =====\n' ...
                '● ENTER NUMBER FIELD\n' ...
                'The numeric input slot where you type values for Insert, Delete, Search, ' ...
                'and Path Traversal actions.\n\n' ...
                '● NODES DISPLAY LABEL\n' ...
                'A dynamic tracker at the bottom left showing the total count of live nodes ' ...
                'currently inside your tree.\n\n' ...
                '===== GRAPH CANVAS =====\n' ...
                '● VISUALIZATION AXES\n' ...
                'Plots your tree using layered graph layouts. Automatically enforces strict left-to-right ' ...
                'sorting for visual clarity.' ...
            ]);
            
            % 2. Trigger the modal popup window overlay
            uialert(app.UIFigure, helpMessage, 'System Function Reference Manual', ...
                'Icon', 'info', ...
                'CloseFcn', @(src, event) app.updatePlot());
        end

        % Button pushed function: ImportButton_2
        function ImportButton_2Pushed(app, event)
            % 1. Open up a file browser popup restricted to .txt and .csv files
            [filename, pathname] = uigetfile({'*.txt;*.csv', 'Data Files (*.txt, *.csv)'}, 'Select your BST Sequence File');
            
            % If the user clicks 'Cancel' or closes the file browser window, exit safely
            if isequal(filename, 0) || isequal(pathname, 0)
                return; 
            end
            
            % 2. Read the sequence numbers from the chosen file path
            fullPath = fullfile(pathname, filename);
            try
                % fileread loads everything as a text string (e.g., "50, 25, 75, 12")
                fileText = fileread(fullPath);
                
                % str2num cleanly parses numbers from text regardless of spaces/commas/newlines
                numSequence = str2num(fileText); 
                
                if isempty(numSequence)
                    error('The file does not contain a valid sequence of numbers.');
                end
            catch ME
                % If something goes wrong reading the file, show an error alert popup
                uialert(app.UIFigure, ['Error reading file: ' ME.message], 'File Import Error', 'Icon', 'error');
                return;
            end
            
            % 3. Clear any existing tree to build a fresh one from our file data
            app.BST = []; 
            cla(app.UIAxes); % Clear the visual plot panel
            
                    % 3. Clear any existing tree to build a fresh one from our file data
        app.BST = []; 
        cla(app.UIAxes); 

        % --- NEW: FILTER & SORT FOR BALANCING ---
        % Clean duplicates first so the median math is perfectly accurate
        numSequence = unique(numSequence, 'stable'); 
        
        % Sort ascending so we can calculate the correct mathematical middle elements
        sortedVals = sort(numSequence); 

        % We will use a temporary queue array to figure out the exact insertion order
        insertionOrder = [];
        insertionOrder = app.calculateBalancedOrder(sortedVals, insertionOrder);

        % 4. Initialize the tree using the first calculated balanced root node
        app.BST = tree(insertionOrder(1));
        app.updatePlot();
        app.refreshStats();
        pause(0.15); 

        % 5. Loop through and insert the rest of the numbers with animation
        for i = 2:numel(insertionOrder)
            val = insertionOrder(i);
            
            % Utilize your app's built-in smartInsert routing algorithm
            app.smartInsert(1, val, 0, 0); 
            
            % --- ANIMATION UPDATE STEP ---
            app.updatePlot(); 
            app.refreshStats(); 
            drawnow; 
            pause(0.4); 
        end

        % Final completed notification alert popup
        uialert(app.UIFigure, ['Successfully built a balanced tree using ' num2str(numel(insertionOrder)) ' nodes!'], 'Animation Complete', 'Icon', 'success');
        end

        % Button pushed function: BaxkButton
        function BaxkButtonPushed(app, event)
            if isempty(app.MainHubAppWindow) || ~isvalid(app.MainHubAppWindow)
                app.MainHubAppWindow = MainHub; % Launches new app
                delete(app.UIFigure);
            else
                figure(app.MainHubAppWindow.UIFigure); % Bring to front
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Binary Search Tree Visualization')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.InsertButton = uibutton(app.UIFigure, 'push');
            app.InsertButton.ButtonPushedFcn = createCallbackFcn(app, @InsertButtonPushed, true);
            app.InsertButton.Text = 'Insert';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.SearchButton = uibutton(app.UIFigure, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.EnterNumberEditFieldLabel = uilabel(app.UIFigure);
            app.EnterNumberEditFieldLabel.Text = 'Enter Number';
            app.EnterNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.TraversalButton = uibutton(app.UIFigure, 'push');
            app.TraversalButton.ButtonPushedFcn = createCallbackFcn(app, @TraversalButtonPushed, true);
            app.TraversalButton.Text = 'Traversal';
            app.NodeCountLabel = uilabel(app.UIFigure);
            app.NodeCountLabel.Text = 'Nodes: 0';
            app.Help = uibutton(app.UIFigure, 'push');
            app.Help.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.Help.Text = '?';
            app.ImportButton_2 = uibutton(app.UIFigure, 'push');
            app.ImportButton_2.ButtonPushedFcn = createCallbackFcn(app, @ImportButton_2Pushed, true);
            app.ImportButton_2.Text = 'Import';
            app.BaxkButton = uibutton(app.UIFigure, 'push');
            app.BaxkButton.ButtonPushedFcn = createCallbackFcn(app, @BaxkButtonPushed, true);
            app.BaxkButton.Text = '<-';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BinaryTreeVisualizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end